classdef icon_split < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Label_13    matlab.ui.control.Label
        InputFile   matlab.ui.control.EditField
        Label_14    matlab.ui.control.Label
        OutputPath  matlab.ui.control.EditField
        OpenFile    matlab.ui.control.Button
        Config      matlab.ui.container.Panel
        Save        matlab.ui.control.Button
        PreView     matlab.ui.control.Button
        Label_15    matlab.ui.control.Label
        TopEdge     matlab.ui.control.Spinner
        Label_16    matlab.ui.control.Label
        ButtonEdge  matlab.ui.control.Spinner
        Label_17    matlab.ui.control.Label
        LeftEdge    matlab.ui.control.Spinner
        Label_18    matlab.ui.control.Label
        RightEdge   matlab.ui.control.Spinner
        Label_19    matlab.ui.control.Label
        RowNums     matlab.ui.control.Spinner
        Label_20    matlab.ui.control.Label
        ColNums     matlab.ui.control.Spinner
        Label_21    matlab.ui.control.Label
        RowEdge     matlab.ui.control.Spinner
        Label_22    matlab.ui.control.Label
        ColEdge     matlab.ui.control.Spinner
        SavePath    matlab.ui.control.Button
        Panel_2     matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        srcFileName % 源文件名字
        srcFilePath % 源文件路径
        dstFilePath % 目标文件路径
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %读取配置,初始化操作
            configVal.topEdgeVal=0;
            configVal.buttonEdgeVal=0;
            configVal.leftEdgeVal=0;
            configVal.rightEdgeVal=0;
            configVal.rowEdgeVal=0;
            configVal.colEdgeVal=0;
            configVal.rowNumsVal=0;
            configVal.colNumsVal=0;
            try
                load config.mat
            catch
                disp('load error');
            end
            
            app.TopEdge.Value=configVal.topEdgeVal;
            app.ButtonEdge.Value=configVal.buttonEdgeVal;
            app.LeftEdge.Value=configVal.leftEdgeVal;
            app.RightEdge.Value=configVal.rightEdgeVal;
            app.RowEdge.Value=configVal.rowEdgeVal;
            app.ColEdge.Value=configVal.colEdgeVal;
            app.RowNums.Value=configVal.rowNumsVal;
            app.ColNums.Value=configVal.colNumsVal;
            app.InputFile.Editable=0;
            app.OutputPath.Editable=0;
            imshow([],'Parent',app.UIAxes);
        end

        % Button pushed function: OpenFile
        function OpenFileButtonPushed(app, event)
            %读取文件,打开文件读取至内存
            [app.srcFileName,app.srcFilePath] = uigetfile(...
           {'*.jpg;*.png;*.bmp;', '图像文件*.jpg, *.png, *.bmp, '; ...
            '*.*',                '所有文件(*.*)'}, ...
            '请选择一个文件');
            if isfile(strcat(app.srcFilePath,app.srcFileName))
                app.InputFile.Value=strcat(app.srcFilePath,app.srcFileName);
                if exist('app.dstFilePath','var')==0
                    app.dstFilePath=app.srcFilePath;
                end
                app.OutputPath.Value=app.dstFilePath;
                PreViewButtonPushed(app,event);
            end
        end

        % Button pushed function: PreView
        function PreViewButtonPushed(app, event)
            %预览操作,根据配置生成效果图
            configVal.topEdgeVal=app.TopEdge.Value;
            configVal.buttonEdgeVal=app.ButtonEdge.Value;
            configVal.leftEdgeVal=app.LeftEdge.Value;
            configVal.rightEdgeVal=app.RightEdge.Value;
            configVal.rowEdgeVal=app.RowEdge.Value;
            configVal.colEdgeVal=app.ColEdge.Value;
            configVal.rowNumsVal=app.RowNums.Value;
            configVal.colNumsVal=app.ColNums.Value;
            configVal.srcFile=strcat(app.srcFilePath,app.srcFileName);

            imshow(piclib('pic_split_preview',configVal),'Parent',app.UIAxes);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %保存配置
            configVal.topEdgeVal=app.TopEdge.Value;
            configVal.buttonEdgeVal=app.ButtonEdge.Value;
            configVal.leftEdgeVal=app.LeftEdge.Value;
            configVal.rightEdgeVal=app.RightEdge.Value;
            configVal.rowEdgeVal=app.RowEdge.Value;
            configVal.colEdgeVal=app.ColEdge.Value;
            configVal.rowNumsVal=app.RowNums.Value;
            configVal.colNumsVal=app.ColNums.Value;
            save config.mat configVal
            delete(app)
        end

        % Button pushed function: SavePath
        function SavePathButtonPushed(app, event)
            app.dstFilePath = uigetdir(app.dstFilePath, '请选择一个文件');
            if isfolder(app.dstFilePath)
                if(app.dstFilePath(end)~='\')
                    app.dstFilePath=strcat(app.dstFilePath,'\');
                end
                app.OutputPath.Value=app.dstFilePath;
            end
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            configVal.topEdgeVal=app.TopEdge.Value;
            configVal.buttonEdgeVal=app.ButtonEdge.Value;
            configVal.leftEdgeVal=app.LeftEdge.Value;
            configVal.rightEdgeVal=app.RightEdge.Value;
            configVal.rowEdgeVal=app.RowEdge.Value;
            configVal.colEdgeVal=app.ColEdge.Value;
            configVal.rowNumsVal=app.RowNums.Value;
            configVal.colNumsVal=app.ColNums.Value;
            configVal.srcFile=strcat(app.srcFilePath,app.srcFileName);
            configVal.srcFileName=app.srcFileName;
            configVal.dstFilePath=app.dstFilePath;
            
            piclib('pic_split',configVal);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '图标分割';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '输入文件';
            app.InputFile = uieditfield(app.UIFigure, 'text');
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '输出目录';
            app.OutputPath = uieditfield(app.UIFigure, 'text');
            app.OpenFile = uibutton(app.UIFigure, 'push');
            app.OpenFile.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFile.Text = '打开文件';
            app.Config = uipanel(app.UIFigure);
            app.Config.Title = '配置区';
            app.Save = uibutton(app.Config, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = '保存';
            app.PreView = uibutton(app.Config, 'push');
            app.PreView.ButtonPushedFcn = createCallbackFcn(app, @PreViewButtonPushed, true);
            app.PreView.Text = '效果预览';
            app.Label_15 = uilabel(app.Config);
            app.Label_15.Text = '上边距';
            app.TopEdge = uispinner(app.Config);
            app.TopEdge.Step = 10;
            app.Label_16 = uilabel(app.Config);
            app.Label_16.Text = '下边距';
            app.ButtonEdge = uispinner(app.Config);
            app.ButtonEdge.Step = 10;
            app.Label_17 = uilabel(app.Config);
            app.Label_17.Text = '左边距';
            app.LeftEdge = uispinner(app.Config);
            app.LeftEdge.Step = 10;
            app.Label_18 = uilabel(app.Config);
            app.Label_18.Text = '右边距';
            app.RightEdge = uispinner(app.Config);
            app.RightEdge.Step = 10;
            app.Label_19 = uilabel(app.Config);
            app.Label_19.Text = '分割行';
            app.RowNums = uispinner(app.Config);
            app.Label_20 = uilabel(app.Config);
            app.Label_20.Text = '分割列';
            app.ColNums = uispinner(app.Config);
            app.Label_21 = uilabel(app.Config);
            app.Label_21.Text = '行间距';
            app.RowEdge = uispinner(app.Config);
            app.RowEdge.Step = 10;
            app.Label_22 = uilabel(app.Config);
            app.Label_22.Text = '列间距';
            app.ColEdge = uispinner(app.Config);
            app.ColEdge.Step = 10;
            app.SavePath = uibutton(app.UIFigure, 'push');
            app.SavePath.ButtonPushedFcn = createCallbackFcn(app, @SavePathButtonPushed, true);
            app.SavePath.Text = '保存路径';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '显示区';
            app.UIAxes = uiaxes(app.Panel_2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = icon_split

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end